classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        GaugeLabel           matlab.ui.control.Label
        Gauge                matlab.ui.control.Gauge
        DebugEditFieldLabel  matlab.ui.control.Label
        DebugEditField       matlab.ui.control.NumericEditField
        UIAxes               matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function result = roundTo(app, arr, val)
            result = interp1(arr,arr,val,'nearest');
        end
        
    end


    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            newValue = app.roundTo(app.Gauge.MinorTicks, value);
            app.DebugEditField.Value = newValue;
            app.Gauge.Value = newValue;
            x2=cos(deg2rad(newValue));
            y2=sin(deg2rad(newValue));
            plot(app.UIAxes,[0 x2],[0 y2])
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            newValue = app.roundTo(app.Gauge.MinorTicks, changingValue);
            app.DebugEditField.Value = newValue;
            app.Gauge.Value = newValue;
            x2=cos(deg2rad(newValue));
            y2=sin(deg2rad(newValue));
            plot(app.UIAxes,[0 x2],[0 y2])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 360];
            app.DebugEditFieldLabel = uilabel(app.UIFigure);
            app.DebugEditFieldLabel.Text = 'Debug';
            app.DebugEditField = uieditfield(app.UIFigure, 'numeric');
            app.DebugEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end