classdef horizontal_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uibuttongroup2  matlab.ui.container.ButtonGroup
        deg15           matlab.ui.control.RadioButton
        deg315          matlab.ui.control.RadioButton
        deg300          matlab.ui.control.RadioButton
        deg285          matlab.ui.control.RadioButton
        deg270          matlab.ui.control.RadioButton
        deg255          matlab.ui.control.RadioButton
        deg240          matlab.ui.control.RadioButton
        deg225          matlab.ui.control.RadioButton
        deg210          matlab.ui.control.RadioButton
        deg195          matlab.ui.control.RadioButton
        deg180          matlab.ui.control.RadioButton
        deg165          matlab.ui.control.RadioButton
        deg150          matlab.ui.control.RadioButton
        deg135          matlab.ui.control.RadioButton
        deg120          matlab.ui.control.RadioButton
        deg105          matlab.ui.control.RadioButton
        deg90           matlab.ui.control.RadioButton
        deg60           matlab.ui.control.RadioButton
        deg45           matlab.ui.control.RadioButton
        deg30           matlab.ui.control.RadioButton
        deg330          matlab.ui.control.RadioButton
        deg345          matlab.ui.control.RadioButton
        deg0            matlab.ui.control.RadioButton
        deg75           matlab.ui.control.RadioButton
        radiobutton85   matlab.ui.control.RadioButton
        radiobutton86   matlab.ui.control.RadioButton
        radiobutton88   matlab.ui.control.RadioButton
        radiobutton87   matlab.ui.control.RadioButton
        radiobutton89   matlab.ui.control.RadioButton
        radiobutton90   matlab.ui.control.RadioButton
        radiobutton92   matlab.ui.control.RadioButton
        radiobutton91   matlab.ui.control.RadioButton
        radiobutton94   matlab.ui.control.RadioButton
        radiobutton95   matlab.ui.control.RadioButton
        radiobutton96   matlab.ui.control.RadioButton
        radiobutton97   matlab.ui.control.RadioButton
        radiobutton98   matlab.ui.control.RadioButton
        radiobutton99   matlab.ui.control.RadioButton
        radiobutton100  matlab.ui.control.RadioButton
        radiobutton101  matlab.ui.control.RadioButton
        radiobutton102  matlab.ui.control.RadioButton
        radiobutton93   matlab.ui.control.RadioButton
        radiobutton103  matlab.ui.control.RadioButton
        radiobutton104  matlab.ui.control.RadioButton
        radiobutton105  matlab.ui.control.RadioButton
        radiobutton106  matlab.ui.control.RadioButton
        radiobutton107  matlab.ui.control.RadioButton
        radiobutton108  matlab.ui.control.RadioButton
        radiobutton109  matlab.ui.control.RadioButton
        radiobutton110  matlab.ui.control.RadioButton
        radiobutton111  matlab.ui.control.RadioButton
        radiobutton112  matlab.ui.control.RadioButton
        radiobutton113  matlab.ui.control.RadioButton
        radiobutton114  matlab.ui.control.RadioButton
        radiobutton115  matlab.ui.control.RadioButton
        radiobutton116  matlab.ui.control.RadioButton
        radiobutton117  matlab.ui.control.RadioButton
        radiobutton118  matlab.ui.control.RadioButton
        radiobutton119  matlab.ui.control.RadioButton
        radiobutton120  matlab.ui.control.RadioButton
        radiobutton121  matlab.ui.control.RadioButton
        radiobutton122  matlab.ui.control.RadioButton
        radiobutton123  matlab.ui.control.RadioButton
        radiobutton124  matlab.ui.control.RadioButton
        radiobutton125  matlab.ui.control.RadioButton
        radiobutton126  matlab.ui.control.RadioButton
        radiobutton127  matlab.ui.control.RadioButton
        radiobutton128  matlab.ui.control.RadioButton
        radiobutton129  matlab.ui.control.RadioButton
        radiobutton130  matlab.ui.control.RadioButton
        radiobutton131  matlab.ui.control.RadioButton
        radiobutton132  matlab.ui.control.RadioButton
        uipanel1        matlab.ui.container.Panel
        SubjectNum      matlab.ui.control.DropDown
        text2           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        edit_rep        matlab.ui.control.EditField
        text3           matlab.ui.control.Label
        text_fs         matlab.ui.control.EditField
        uipanel2        matlab.ui.container.Panel
        Next            matlab.ui.control.Button
        Save1           matlab.ui.control.Button
        Replay          matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        function horizontal_OpeningFcn(app,hObject, eventdata, handles, varargin)
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to horizontal (see VARARGIN)
            % HRTF load in
            num = 0; % number of hrtfs loaded
            path = uigetdir(pwd,'Please select the folder');
            filelist = dir(sprintf('%s/*.mat',path));
            for i = 1 : length(filelist)
                clear data specs
                num = num + 1;
                fprintf('Now loading: %s\n',filelist(i).name);
                hrtf_struct(i)=load(sprintf('%s/%s',path,filelist(i).name));
                %     hrtf_struct(i).az = az;
                %     hrtf_struct(i).hrir_l = hrir_l;
                %     hrtf_struct(i).num = num;
                %     hrtf_struct(i) = filelist(i).name
            end
            
            app.hrtf_struct=hrtf_struct;

        function PlaySound_10_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_10.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_10_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(10)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(10))).azimuth
        end
        
        function PlaySound_11_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_11.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_11_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(11)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(11))).azimuth
        end
        
        function PlaySound_12_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_12.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_12_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(12)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(12))).azimuth
        end
        
        function PlaySound_2_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_2.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_2_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(2)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(2))).azimuth
        end
        
        function PlaySound_3_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_3.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_3_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(3)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(3))).azimuth
        end
        
        function PlaySound_4_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_4.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_4_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(4)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(4))).azimuth
        end
        
        function PlaySound_5_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_5.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(5)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(5))).azimuth
        end
        
        function PlaySound_6_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_6.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_6_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(6)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(6))).azimuth
        end
        
        function PlaySound_7_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_7.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_7_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(7)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(7))).azimuth
        end
        
        function PlaySound_8_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_8.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_8_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(8)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(8))).azimuth
        end
        
        function PlaySound_9_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.PlaySound_9.BackgroundColor=[0,0.94,0];
        end
        
        function PlaySound_9_Callback(app, hObject, eventdata, handles)
            % hObject    handle to PlaySound_9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.hrir=app.hrtf_struct.data(app.R_azs_num(9)).IR;
            IR=app.hrir;
            left = conv(IR(:,1),app.mono);
            right = conv(IR(:,2),app.mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs);
            azs=app.hrtf_struct.data(app.ind(app.R_azs_num(9))).azimuth
        end
        
        function Save10_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save10,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save11_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save11,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save12_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save12,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save2_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result
            save('test_result.mat','test_result');
            set(app.Save2,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save3_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result
            save('test_result.mat','test_result');
            set(app.Save3,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save4_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save4,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save5,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save6_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save6,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save7_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save7,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save8_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save8,'Enable','off')
            guidata(hObject, handles);
        end
        
        function Save9_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Save9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+15)/15;
            app.test_result(iLoc)=app.test_result(iLoc)+1;
            test_result=app.test_result;
            save('test_result.mat','test_result');
            set(app.Save9,'Enable','off')
            guidata(hObject, handles);
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function y = mk_infrapitch(app, handles)
            app.durBase = 1/app.sigRep; % 0.2 per base
            for i=1:length(app.fs)
                infraBase(i).base = mk_pink(ceil(app.fs(i)*app.durBase));
            end
            
            for i=1:length(infraBase)
                app.fullSig(i).fullsig = [];
                for iRep = 1:app.sigRepPrep
                    app.fullSig(i).fullsig = [app.fullSig(i).fullsig, infraBase(i).base];
                end
                mono(i).mono = app.fullSig(i).fullsig;
                mono_for_save=mono(i).mono;
                filenm=['infrapitch' num2str(i) '.mat'];
                save(filenm, 'mono_for_save')
            
            end
            app.mono = mono;
            
            %app.mono = app.fullSig(1:ceil(min(app.fs*app.sigLength, length(app.fullSig))));
            y = handles;
        end
        
        function pinkn = mk_pink(app, npts)
            
            Den = poly([0.99572754 0.94790649 0.53567505]);    %set up the denominator and numerator polynomials for a digital IIR pink-noise filter
            Num = poly([0.98443604 0.83392334 0.07568359]);
            
            seg = rand(size(1:npts));
            seg = seg - mean(seg);
            pinkn = filter(Num,Den,seg);
        end
        
        function y = mk_signal(app, handles)
            clear mono
            clear app.mono
            handles = mk_infrapitch(handles);
            display('making infrapitch');
            y = handles;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function horizontal_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to horizontal (see VARARGIN)
%             % HRTF load in
%             num = 0; % number of hrtfs loaded
%             path = uigetdir(pwd,'Please select the folder');
%             filelist = dir(sprintf('%s/*.mat',path));
%             for i = 1 : length(filelist)
%                 clear data specs
%                 num = num + 1;
%                 fprintf('Now loading: %s\n',filelist(i).name);
%                 hrtf_struct(i)=load(sprintf('%s/%s',path,filelist(i).name));
%             %     hrtf_struct(i).az = az;
%             %     hrtf_struct(i).hrir_l = hrir_l;
%             %     hrtf_struct(i).num = num;
%             %     hrtf_struct(i) = filelist(i).name
%             end
%             
%             app.hrtf_struct=hrtf_struct;
%             
%             % Initialization the hrtf
%             % app.R_hrtf_num=floor(1+length(app.hrtf_struct).*rand(1,1));
%             % app.hrtf_struct=hrtf_struct(app.R_hrtf_num);
%             % ind = []; % indices for horizontal plane
%             % data=app.hrtf_struct;
%             % for j = 1 : length(app.hrtf_struct)
%             %     if data(j).el == 0 && mod(data(j).az,30)==0
%             %          ind = [ind, j];% index for elevation equal to zero
%             %          app.ind=ind;
%             %     end
%             % end
%             
%             
%             %% NEED TO BE MODIFY
%             %--generate 12 test points or 24
%             azs_index=[];
%             azs_ground_truth=[];
%             for i=1:24
%                 azs_index=[azs_index,i];
%                 %app.hrir=app.hrtf_struct.data(app.R_azs_num).IR;
%             end
%             app.azs_index=azs_index;
%             R_azs_num=azs_index(randperm(length(app.azs_index)));
%             app.R_azs_num=R_azs_num;
%             
%             %--generate HRTF order
%             HRTF_index=[];
%             HRTF_ground_truth=[];
%             for i=1:length(app.hrtf_struct)
%                 HRTF_index=[HRTF_index,i];
%                 %app.hrir=app.hrtf_struct.data(app.R_azs_num).IR;
%             end
%             app.HRTF_index=HRTF_index;
%             R_HRTF_index=HRTF_index(randperm(length(app.HRTF_index)));
%             app.R_HRTF_index=R_HRTF_index;
%             
%             %--store azithum ground truth
%             for i=1:24
%                 azs=app.hrtf_struct(1).HRTF(app.R_azs_num(i)).az;
%                 azs_ground_truth=[azs_ground_truth,azs];
%             end
%             app.azs_ground_truth=azs_ground_truth;
%             save('azs_ground_truth_1.mat', 'azs_ground_truth')
%             
%             %--initiate an array to store test result
%             choice_result=zeros(1,72);
%             app.choice_result=choice_result;
%             app.choice_result_r=app.choice_result;
%             % to initialize the sampling frequency
%             for i=1:length(app.hrtf_struct)
%                 app.fs(i) = app.hrtf_struct(i).HRTF(1).srate;
%             end
%             set(app.text_fs,'String',app.hrtf_struct(R_HRTF_index(1)).HRTF(1).srate)
%             % initialize a counter for count click save number
%             click_num=1;
%             app.click_num=click_num;
%             % initialize a counter for count test times
%             test_num=1;
%             app.test_num=test_num;
%             % to specify parameters for making input
%             app.sigRep = 10;
%             app.sigRepPrep = 10;
%             % to make input signal
%             handles = mk_signal(handles); % to be saved in app.mono
%             % Set the timer
%             app.timer = timer('StartDelay',0.5, ...
%                 'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
%                 'Period', 2, ...                        % Initial period is 1 sec.
%                 'TimerFcn', {@Playsound,hObject}); % Specify callback function
%             
%             %initiate save button
%             app.Save1.Enable='off';
%             
%             % Choose default command line output for horizontal
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: Next
        function Next_Callback(app, event)
            % hObject    handle to Next (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.SubjectNum,'Enable','inactive')
            app.HRTF_Pick=app.R_HRTF_index(mod(app.click_num,length(app.hrtf_struct))+1);
            app.hrir_r=app.hrtf_struct(app.HRTF_Pick).HRTF(app.R_azs_num(app.click_num)).hrir_r;
            app.hrir_l=app.hrtf_struct(app.HRTF_Pick).HRTF(app.R_azs_num(app.click_num)).hrir_l;
            IR_r=app.hrir_r;
            IR_l=app.hrir_l;
            left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
            right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            set(app.text_fs,'String',app.fs(app.HRTF_Pick))
            sound(v,app.fs(app.HRTF_Pick));
            set(app.Next,'Enable','off')
            set(app.Save1,'Enable','on')
            %start a timer
            time=cputime;
            app.time=time;
            
            azs=(app.R_azs_num(app.click_num)-1)*15
            guidata(hObject, handles);
        end

        % Button pushed function: Replay
        function Replay_Callback(app, event)
            % hObject    handle to Replay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            IR_r=app.hrir_r;
            IR_l=app.hrir_l;
            left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
            right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
            if isrow(left)
                left = left';
                right = right';
                v = [left,right];
            end
            sound(v,app.fs(app.HRTF_Pick));
        end

        % Button pushed function: Save1
        function Save1_Callback(app, event)
            % hObject    handle to Save1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %--compute decision time
            app.e=cputime-app.time;
            %--the choice of subject
            deg_picked = str2num(get(get(app.uibuttongroup2,'SelectedObject'), 'String'));
            iLoc=(deg_picked+5)/5;
            app.choice_result(iLoc)=app.choice_result(iLoc)+1;
            choice_result=app.choice_result;
            %--establish a struct to store user result
            app.test_result(app.test_num).subject=get(app.SubjectNum,'Value');
            app.test_result(app.test_num).HRTF_BASE=app.R_HRTF_index(app.HRTF_Pick);
            app.test_result(app.test_num).Ground_Truth=app.azs_ground_truth(app.click_num);
            app.test_result(app.test_num).Subject_Choice=deg_picked;
            app.test_result(app.test_num).Choice_sumup=choice_result;
            app.test_result(app.test_num).time=app.e;
            test_result=app.test_result;
            filenm=['test_result_sub' num2str(test_result(1).subject) '.mat'];
            save(filenm,'test_result');
            save('choice_result.mat','choice_result');
            set(app.Save1,'Enable','off');
            set(app.Next,'Enable','on');
            app.click_num=app.click_num+1;
            if mod(app.click_num,3)==0
                app.R_HRTF_index=app.HRTF_index(randperm(length(app.HRTF_index)));
            end
            if app.click_num==12
                app.R_azs_num=app.azs_index(randperm(length(app.azs_index)));
                app.click_num=1;
            end
            app.test_num=app.test_num+1;
            set(app.edit_rep,'String',app.test_num)
            if app.test_num==120
                msgbox('Complete')
                set(app.Save1,'Enable','off');
                set(app.Next,'Enable','off');
            end
            guidata(hObject, handles);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'horizontal';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Select Area';
            app.deg15 = uiradiobutton(app.uibuttongroup2);
            app.deg15.Text = '15';
            app.deg15.Value = true;
            app.deg315 = uiradiobutton(app.uibuttongroup2);
            app.deg315.Text = '315';
            app.deg300 = uiradiobutton(app.uibuttongroup2);
            app.deg300.Text = '300';
            app.deg285 = uiradiobutton(app.uibuttongroup2);
            app.deg285.Text = '285';
            app.deg270 = uiradiobutton(app.uibuttongroup2);
            app.deg270.Text = '270';
            app.deg255 = uiradiobutton(app.uibuttongroup2);
            app.deg255.Text = '255';
            app.deg240 = uiradiobutton(app.uibuttongroup2);
            app.deg240.Text = '240';
            app.deg225 = uiradiobutton(app.uibuttongroup2);
            app.deg225.Text = '225';
            app.deg210 = uiradiobutton(app.uibuttongroup2);
            app.deg210.Text = '210';
            app.deg195 = uiradiobutton(app.uibuttongroup2);
            app.deg195.Text = '195';
            app.deg180 = uiradiobutton(app.uibuttongroup2);
            app.deg180.Text = '180';
            app.deg165 = uiradiobutton(app.uibuttongroup2);
            app.deg165.Text = '165';
            app.deg150 = uiradiobutton(app.uibuttongroup2);
            app.deg150.Text = '150';
            app.deg135 = uiradiobutton(app.uibuttongroup2);
            app.deg135.Text = '135';
            app.deg120 = uiradiobutton(app.uibuttongroup2);
            app.deg120.Text = '120';
            app.deg105 = uiradiobutton(app.uibuttongroup2);
            app.deg105.Text = '105';
            app.deg90 = uiradiobutton(app.uibuttongroup2);
            app.deg90.Text = '90';
            app.deg60 = uiradiobutton(app.uibuttongroup2);
            app.deg60.Text = '60';
            app.deg45 = uiradiobutton(app.uibuttongroup2);
            app.deg45.Text = '45';
            app.deg30 = uiradiobutton(app.uibuttongroup2);
            app.deg30.Text = '30';
            app.deg330 = uiradiobutton(app.uibuttongroup2);
            app.deg330.Text = '330';
            app.deg345 = uiradiobutton(app.uibuttongroup2);
            app.deg345.Text = '345';
            app.deg0 = uiradiobutton(app.uibuttongroup2);
            app.deg0.Text = '0';
            app.deg75 = uiradiobutton(app.uibuttongroup2);
            app.deg75.Text = '75';
            app.radiobutton85 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton85.Text = '5';
            app.radiobutton86 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton86.Text = '10';
            app.radiobutton88 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton88.Text = '25';
            app.radiobutton87 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton87.Text = '20';
            app.radiobutton89 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton89.Text = '35';
            app.radiobutton90 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton90.Text = '40';
            app.radiobutton92 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton92.Text = '55';
            app.radiobutton91 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton91.Text = '50';
            app.radiobutton94 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton94.Text = '70';
            app.radiobutton95 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton95.Text = '80';
            app.radiobutton96 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton96.Text = '85';
            app.radiobutton97 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton97.Text = '95';
            app.radiobutton98 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton98.Text = '100';
            app.radiobutton99 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton99.Text = '110';
            app.radiobutton100 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton100.Text = '115';
            app.radiobutton101 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton101.Text = '125';
            app.radiobutton102 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton102.Text = '130';
            app.radiobutton93 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton93.Text = '65';
            app.radiobutton103 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton103.Text = '140';
            app.radiobutton104 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton104.Text = '145';
            app.radiobutton105 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton105.Text = '155';
            app.radiobutton106 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton106.Text = '160';
            app.radiobutton107 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton107.Text = '170';
            app.radiobutton108 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton108.Text = '175';
            app.radiobutton109 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton109.Text = '185';
            app.radiobutton110 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton110.Text = '190';
            app.radiobutton111 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton111.Text = '200';
            app.radiobutton112 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton112.Text = '205';
            app.radiobutton113 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton113.Text = '215';
            app.radiobutton114 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton114.Text = '220';
            app.radiobutton115 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton115.Text = '230';
            app.radiobutton116 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton116.Text = '235';
            app.radiobutton117 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton117.Text = '245';
            app.radiobutton118 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton118.Text = '250';
            app.radiobutton119 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton119.Text = '260';
            app.radiobutton120 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton120.Text = '265';
            app.radiobutton121 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton121.Text = '275';
            app.radiobutton122 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton122.Text = '280';
            app.radiobutton123 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton123.Text = '295';
            app.radiobutton124 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton124.Text = '295';
            app.radiobutton125 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton125.Text = '305';
            app.radiobutton126 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton126.Text = '310';
            app.radiobutton127 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton127.Text = '320';
            app.radiobutton128 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton128.Text = '325';
            app.radiobutton129 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton129.Text = '335';
            app.radiobutton130 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton130.Text = '340';
            app.radiobutton131 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton131.Text = '350';
            app.radiobutton132 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton132.Text = '355';
            app.uipanel1 = uipanel(app.figure1);
            app.SubjectNum = uidropdown(app.uipanel1);
            app.SubjectNum.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22'};
            app.SubjectNum.Value = '1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Subject';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Repetition';
            app.edit_rep = uieditfield(app.uipanel1, 'text');
            app.edit_rep.Enable = 'off';
            app.edit_rep.Value = '0';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'fs';
            app.text_fs = uieditfield(app.uipanel1, 'text');
            app.text_fs.Enable = 'off';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Sound Play';
            app.Next = uibutton(app.uipanel2, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @Next_Callback, true);
            app.Next.Text = 'Next';
            app.Save1 = uibutton(app.uipanel2, 'push');
            app.Save1.ButtonPushedFcn = createCallbackFcn(app, @Save1_Callback, true);
            app.Save1.Text = 'Confirm';
            app.Replay = uibutton(app.uipanel2, 'push');
            app.Replay.ButtonPushedFcn = createCallbackFcn(app, @Replay_Callback, true);
            app.Replay.Text = 'Replay';
        end
    end

    methods (Access = public)

        % Construct app
        function app = horizontal_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @horizontal_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end