classdef horizontal_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ProgressGaugeLabel           matlab.ui.control.Label
        ProgressGauge                matlab.ui.control.LinearGauge
        Panel_2                      matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        DegreeAdjustmentSliderLabel  matlab.ui.control.Label
        DegreeAdjustmentSlider       matlab.ui.control.Slider
        DegreeEditFieldLabel         matlab.ui.control.Label
        DegreeEditField              matlab.ui.control.NumericEditField
        PlaySoundButton              matlab.ui.control.Button
        ConfirmChoiceButton          matlab.ui.control.Button
        SubjectEditFieldLabel        matlab.ui.control.Label
        SubjectEditField             matlab.ui.control.NumericEditField
        FSHZEditFieldLabel           matlab.ui.control.Label
        FSHZEditField                matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        PauseButton                  matlab.ui.control.StateButton
        PausingLabel                 matlab.ui.control.Label
        TrainingButton               matlab.ui.control.StateButton
        StartButton                  matlab.ui.control.StateButton
        ReloadButton                 matlab.ui.control.Button
        InstructionTextAreaLabel     matlab.ui.control.Label
        InstructionTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        hrtf_struct; % Description
        fs % Description
        train_hrtf_array
        train_azs_array
        repeat_train
        R_hrtf_array
        R_azs_array
        sigRep
        sigRepPrep
        makesignal
        durBase % Description
        fullSig
        mono
        playsound
        azs_ground_truth
        choice_result % Description
        click_num
        test_num
        repeat % Description
        HRTF_Pick
        az_Pick
        hrir_r
        hrir_l
        time
        choice_time % Description
        test_result % Description
        gauge
        history % Description
    end
    
    methods (Access = private)
        
        %%--- Make Stimuli
        
        function pinkn = mk_pink(app,npts)
            
            Den = poly([0.99572754 0.94790649 0.53567505]);    %set up the denominator and numerator polynomials for a digital IIR pink-noise filter
            Num = poly([0.98443604 0.83392334 0.07568359]);
            
            seg = rand(size(1:npts));
            seg = seg - mean(seg);
            pinkn = filter(Num,Den,seg);      %create the pink noise signal
        end
        % --- make infrapitch
        function y = mk_infrapitch(app)
            app.durBase = 1/app.sigRep; % 0.2 per base
            for i=1:length(app.fs)
                infraBase(i).base = mk_pink(app,ceil(app.fs(i)*app.durBase));
            end
            
            for i=1:length(infraBase)
                app.fullSig(i).fullsig = [];
                for iRep = 1:length(app.sigRepPrep)
                    app.fullSig(i).fullsig = [app.fullSig(i).fullsig, infraBase(i).base];
                end
                app.mono(i).mono = app.fullSig(i).fullsig;
                mono_for_save=app.mono(i).mono;
                filenm=['infrapitch' num2str(i) '.mat'];
                save(filenm, 'mono_for_save');
                
            end


            %app.mono = app.fullSig(1:ceil(min(app.fs*app.sigLength, length(app.fullSig))));
            y = app;
        end
        
        function y = mk_signal(app)
            clear mono
            clear app.mono
            app = mk_infrapitch(app);
            disp('making infrapitch');
            y = app;
        end
        
        % generate a random play list of hrtf, 3 hrtfs randomly but on quantity evenly scatter into 120 repetations
        function R_hrtf_array=make_hrtf_play_list(app)
            hrtf_index=[];
            for i=1:length(app.hrtf_struct)
                
                for j=1:60
                    hrtf_index=[hrtf_index,i];
                end
            end
            R_hrtf_array=hrtf_index;
%             R_hrtf_array=hrtf_index(randperm(length(hrtf_index)));
            
        end
        % generate a random play list of azimuth, 0,30,60,90,120,150,180,210,240,270,300,330 evenly scatter in the list.
        function R_azs_array=make_azs_play_list(app)
            azs_index=[];
            for i=1:15
                for j=1:12
                    azs_index=[azs_index,j];
                end
            end
            R_azs_array=azs_index;
%             R_azs_array=azs_index(randperm(length(azs_index)));
%             for i=1:length(R_azs_array)
%                 app.azs_ground_truth(i)=app.hrtf_struct(1).HRTF(R_azs_array(i)).az;
%             end
        end
        
        %         function []=PlaySound(app,event)
        %                 %select one htrf from playlist
        %                 app.HRTF_Pick=app.R_hrtf_array(app.click_num);
        %                 %select one azimuth from playlist
        %                 app.az_Pick=app.R_azs_array(app.click_num);
        %
        %                 app.hrir_r=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_r;
        %                 app.hrir_l=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_l;
        %                 IR_r=app.hrir_r;
        %                 IR_l=app.hrir_l;
        %                 left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
        %                 right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
        %                 if isrow(left)
        %                     left = left';
        %                     right = right';
        %                     v = [left,right];
        %                 end
        %                 sound(v,app.fs(app.HRTF_Pick));
        %                 set(app.FSHZEditField,'Value',app.click_num);
        %             end
        %


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlaySoundButton.Enable='off';
            app.ConfirmChoiceButton.Enable='off';
            app.PausingLabel.Visible='off';
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
            %app.AnglePickButtonGroup.Children = flip(app.AnglePickButtonGroup.Children);
            
            % Fill figure with axes and remove tick labels
            app.UIAxes2.Position = [0.56*app.UIAxes.Position(3:4),80,80];
            %             Remove title, axis labels, and tick labels
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
            %             Display image and stretch to fill axes
            I = imshow('user_oriental.jpg', 'Parent', app.UIAxes2);
            %             'XData', [1 app.UIAxes2.Position(3)], ...
            %                 'YData', [1 app.UIAxes2.Position(4)]);
            %Set limits of axes
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];
            num = 0; % number of hrtfs loaded
            path = uigetdir(pwd,'Please select the folder');
            filelist = dir(sprintf('%s/*.mat',path));
            for i = 1 : length(filelist)
                clear data specs
                num = num + 1;
                fprintf('Now loading: %s\n',filelist(i).name);
                data_struct=load(sprintf('%s/%s',path,filelist(i).name));
                app.hrtf_struct(i).HRTF= data_struct.HRTF;
                %                 app.hrtf_struct(i).HRTF.hrir_l= data_struct.HRTF.hrir_l;
                %                 app.hrtf_struct(i).HRTF.hrir_r= data_struct.HRTF.hrir_r;
                %                 app.hrtf_struct(i).HRTF.srate= data_struct.HRTF.srate;
                %                 app.hrtf_struct(i).name = filelist(i).name;
            end
            %generate a training play list
            app.train_hrtf_array=[1,1,1,1,1,1,1,1,1,1,1,1];
            app.train_azs_array=[1,2,3,4,5,6,7,8,9,10,11,12];
            
            %generate a hrtf play list for test
            %vertically Concatenating hrtf and azs array
            Play_list=[make_hrtf_play_list(app);make_azs_play_list(app)];
            %Randomly sort the playlist
            col_index=randperm(size(Play_list,2));
            Play_list=Play_list(:,col_index);
            %asign the hrtf play list
            app.R_hrtf_array=Play_list(1,:);
            R_hrtf_array_for_save=app.R_hrtf_array;
            %generate a azimuth play list for test 
            app.R_azs_array=Play_list(2,:);
            R_azs_array_for_save=app.R_azs_array;
                        
            %Check the number of azs in total
%             d=zeros(3,12);
%             for i=1:180
%                 for j=1:12
%                     if R_hrtf_array_for_save(1,i)==3 && R_azs_array_for_save(1,i)==j
%                         d(3,j)=d(3,j)+1;
%                     end
%                 end
%             end
%             

            %generate the groundtruth of azs
            for i=1:length(app.R_azs_array)
            app.azs_ground_truth(i)=app.hrtf_struct(1).HRTF(app.R_azs_array(i)).az;
            end
            
            % to initialize the sampling frequency
            for i=1:length(app.hrtf_struct)
                app.fs(i) = app.hrtf_struct(i).HRTF(1).srate;
            end
            set(app.FSHZEditField,'Value',app.hrtf_struct(app.R_hrtf_array(1)).HRTF(1).srate)
            
            % to specify parameters for making input
            app.sigRep = 5/2;
            app.sigRepPrep = 1;
            % to make input signal
            app.makesignal = mk_signal(app); % to be saved in app.mono
            % Set the timer
            app.repeat = timer('StartDelay',0.5, ...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
                'Period', 2, ...                        % Initial period is 1 sec.
                'TasksTOExecute', 3, ...
                'TimerFcn', {@PlaySound,app},...
                'StopFCn', {@Stop_Fcn,app}); % Specify callback function
            function []=PlaySound(varargin)
                %select one htrf from playlist
                app.HRTF_Pick=app.R_hrtf_array(app.click_num);
                %select one azimuth from playlist
                app.az_Pick=app.R_azs_array(app.click_num);
                
                app.hrir_r=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_r;
                app.hrir_l=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_l;
                IR_r=app.hrir_r;
                IR_l=app.hrir_l;
                left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
                right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
                %                 left = app.mono(app.HRTF_Pick).mono;
                %                 right = app.mono(app.HRTF_Pick).mono;
                if isrow(left)
                    left = left';
                    right = right';
                    v = [left,right];
                end
                sound(v,app.fs(app.HRTF_Pick));
                set(app.FSHZEditField,'Value',app.hrtf_struct(app.R_hrtf_array(app.click_num)).HRTF(1).srate);
                
            end
            function []= Stop_Fcn(varargin)
                 %start a timer
                app.time=cputime;
                set(app.ConfirmChoiceButton,'Enable','on')
            end
        
            
            % repeat for training
            app.repeat_train = timer('StartDelay',0.5, ...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
                'Period', 2, ...                        % Initial period is 1 sec.
                'TasksTOExecute', 3, ...
                'TimerFcn', {@PlaySound_train,app},...
                'StopFCn', @(x,y)set(app.ConfirmChoiceButton,'Enable','on')); % Specify callback function
            function []=PlaySound_train(varargin)
                %select one htrf from playlist
                app.HRTF_Pick=app.train_hrtf_array(app.click_num);
                %select one azimuth from playlist
                app.az_Pick=app.train_azs_array(app.click_num);
                
                app.hrir_r=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_r;
                app.hrir_l=app.hrtf_struct(app.HRTF_Pick).HRTF(app.az_Pick).hrir_l;
                IR_r=app.hrir_r;
                IR_l=app.hrir_l;
                left = conv(IR_l,app.mono(app.HRTF_Pick).mono);
                right = conv(IR_r,app.mono(app.HRTF_Pick).mono);
                %                 left = app.mono(app.HRTF_Pick).mono;
                %                 right = app.mono(app.HRTF_Pick).mono;
                if isrow(left)
                    left = left';
                    right = right';
                    v = [left,right];
                end
                sound(v,app.fs(app.HRTF_Pick));
                set(app.FSHZEditField,'Value',app.hrtf_struct(app.train_hrtf_array(app.click_num)).HRTF(1).srate);
                
            end
            %--initiate an array to store test result
            app.choice_result=zeros(1,72);
            % initialize a counter for count click save number
            app.click_num=1;
%             % initialize a counter for count test times
%             app.test_num=1;
            %initiate the degree interpolation array
            app.gauge=[0];
            for i=1:360
                if mod(i,5)==0
                    app.gauge=[app.gauge,i];
                end
            end
            %plot the mark every 5degree
            
            y2=0.75*cos(deg2rad(app.gauge));
            x2=0.75*sin(deg2rad(app.gauge));
            plot(app.UIAxes,-x2,y2,'ro')
            
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            % train mode
            if app.TrainingButton.Value==1
                set(app.SubjectEditField,'Editable','off')
                
                start(app.repeat_train);
                
                %disable the Playsound button
                app.PlaySoundButton.Enable='off';
                azs=app.hrtf_struct(app.train_hrtf_array(app.click_num)).HRTF(app.train_azs_array(app.click_num)).az;
            end
            % test mode
            if app.TrainingButton.Value==0
                set(app.SubjectEditField,'Editable','off');
                
                start(app.repeat);
                
%                 %start a timer
%                 app.time=cputime;
                %disable the Playsound button
                app.PlaySoundButton.Enable='off';
                app.PauseButton.Enable='off';
                azs=(app.azs_ground_truth(app.click_num));
            end
            
            
        end

        % Button pushed function: ConfirmChoiceButton
        function ConfirmChoiceButtonPushed(app, event)
            % train mode
            if app.TrainingButton.Value==1
                set(app.ConfirmChoiceButton,'Enable','off');
                set(app.PlaySoundButton,'Enable','on');
                
                
                if app.click_num==12
                    
                    set(app.ConfirmChoiceButton,'Enable','off');
                    set(app.PlaySoundButton,'Enable','off');
                    h=questdlg('Training Complete','','OK','OK');
                    switch h
                        case 'OK'
                            app.click_num=1;
                            app.TrainingButton.Value=0;
                            app.TrainingButton.Text='Training';
                            
                        case ''
                            app.click_num=1;
                            app.TrainingButton.Value=0;
                            app.TrainingButton.Text='Training';
                        otherwise
                    end
                end
                %update ProgressGauge
                app.click_num=app.click_num+1;
                set(app.ProgressGauge,'Value',app.click_num)
            end


            % test mode
            if app.TrainingButton.Value==0
                %--compute decision time
                app.choice_time=cputime-app.time;
                %--the choice of subject
                deg_picked = app.DegreeEditField.Value;
%                 iLoc=(deg_picked+5)/5;
%                 app.choice_result(iLoc)=app.choice_result(iLoc)+1;
%                 choice_result_store=app.choice_result;
                %--establish a struct to store user result
                app.test_result(app.click_num).subject=get(app.SubjectEditField,'Value');
                app.test_result(app.click_num).HRTF_BASE=app.R_hrtf_array(app.click_num);
                app.test_result(app.click_num).Ground_Truth=app.azs_ground_truth(app.click_num);
                app.test_result(app.click_num).Subject_Choice=deg_picked;
%                 app.test_result(app.click_num).Choice_sumup=app.choice_result;
                app.test_result(app.click_num).time=app.choice_time;
                test_result=app.test_result;
                filenm=['test_result_sub_' num2str(app.SubjectEditField.Value) '.mat'];
                %get the directory of your input files:
                pathname = pwd;
                %use that when you save
                result_file = fullfile(pathname,'Result', filenm);
                save(result_file, 'test_result');
                %save user history
                app.history(1)=app.SubjectEditField.Value;
                app.history(2)=app.click_num;
                history_for_save=app.history;
                filenm=['user_history.mat'];
                %get the directory of your input files:
                pathname = pwd;
                %use that when you save
                history_file = fullfile(pathname,'Reload', filenm);
                save(history_file, 'history_for_save');
                
                %save('choice_result.mat','choice_result_store');
                set(app.ConfirmChoiceButton,'Enable','off');
                set(app.PlaySoundButton,'Enable','on');
                app.PauseButton.Enable='on';
                if app.click_num==180
                    msgbox('Complete');
                    set(app.ConfirmChoiceButton,'Enable','off');
                    set(app.PlaySoundButton,'Enable','off');
                end
                app.click_num=app.click_num+1;
                %update ProgressGauge
                set(app.ProgressGauge,'Value',app.click_num);
                
            end
            
            
        end

        % Value changed function: DegreeAdjustmentSlider
        function DegreeAdjustmentSliderValueChanged(app, event)
            %plot the mark every 5degree
            
            marky=0.75*cos(deg2rad(app.gauge));
            markx=0.75*sin(deg2rad(app.gauge));
            plot(app.UIAxes,-markx,marky,'bo')
            
            
            value = app.DegreeAdjustmentSlider.Value;
%             new_value=interp1(app.gauge,app.gauge,value,'nearest');
            y2=0.7*cos(deg2rad(floor(value)));
            x2=0.7*sin(deg2rad(floor(value)));
            app.DegreeEditField.Value=floor(value);
            plot(app.UIAxes,[0 -x2],[0 y2],-markx,marky,'ro')


        end

        % Value changing function: DegreeAdjustmentSlider
        function DegreeAdjustmentSliderValueChanging(app, event)
            %plot the mark every 5degree
            
            marky=0.75*cos(deg2rad(app.gauge));
            markx=0.75*sin(deg2rad(app.gauge));
            plot(app.UIAxes,-markx,marky,'bo')
            
            
            changingValue = event.Value;
%             new_changingValue=interp1(app.gauge,app.gauge,changingValue,'nearest');
            y2=0.7*cos(deg2rad(floor(changingValue)));
            x2=0.7*sin(deg2rad(floor(changingValue)));
            app.DegreeEditField.Value=floor(changingValue);
            plot(app.UIAxes,[0 -x2],[0 y2],-markx,marky,'ro')
            
            
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if value==1
                app.Panel_2.Visible='off';
                app.PauseButton.Text='Unpause';
                app.PausingLabel.Position=[100,322,843,119];
                app.PausingLabel.Visible='on';
            end
            if value==0
                app.Panel_2.Visible='on';
                app.PauseButton.Text='Pause';
                app.PausingLabel.Visible='off';
            end
            
            
        end

        % Value changed function: TrainingButton
        function TrainingButtonValueChanged(app, event)
            value = app.TrainingButton.Value;
            if value ==1
                app.click_num=1;
                app.StartButton.Enable='off';
                app.PlaySoundButton.Enable='on';
                app.ConfirmChoiceButton.Enable='off';
                app.ReloadButton.Enable='off';
                app.TrainingButton.Text='To End Training';
            end
            if value ==0
                app.click_num=1;
                app.StartButton.Enable='on';
                app.ReloadButton.Enable='on';
                app.TrainingButton.Text='Training';
                app.PlaySoundButton.Enable='off';
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value==1
                
                app.TrainingButton.Enable='off';
                app.StartButton.Enable='off';
                app.PlaySoundButton.Enable='on';
                app.ReloadButton.Enable='off';
                
                %save the hrtf and azi array of this test for safety function
                %get the directory of your input files:
                pathname = pwd;
                %use that when you save
                R_hrtf_array_for_save=app.R_hrtf_array;
                R_azs_array_for_save=app.R_azs_array;
                hrtffile = fullfile(pathname,'Reload', 'R_hrtf_array.mat');
                azsfile = fullfile(pathname, 'Reload','R_azs_array.mat');
                save(hrtffile, 'R_hrtf_array_for_save');
                save(azsfile, 'R_azs_array_for_save');
            end
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            current_path=pwd;
            %Reload hrtf array
            reload_file = fullfile(current_path,'Reload', 'R_hrtf_array.mat'); 
            reload_file_temp=load(reload_file);
            app.R_hrtf_array=reload_file_temp.R_hrtf_array_for_save;
            %Reload azs array
            reload_file = fullfile(current_path,'Reload', 'R_azs_array.mat'); 
            reload_file_temp=load(reload_file);
            app.R_azs_array=reload_file_temp.R_azs_array_for_save;
            %Reload user history
            reload_file = fullfile(current_path,'Reload', 'user_history.mat');            
            history_temp=load(reload_file);
            app.SubjectEditField.Value=history_temp.history_for_save(1,1);
            app.click_num=history_temp.history_for_save(1,2);
            %Reload last time test result
            filenm=['test_result_sub_' num2str(app.SubjectEditField.Value) '.mat'];
            reload_file = fullfile(current_path,'Result', filenm); 
            reload_file_temp=load(reload_file);
            app.test_result=reload_file_temp.test_result;
            
            msgbox('Reload Complete','Reload')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'linear');
            app.ProgressGauge.Limits = [1 180];
            app.ProgressGauge.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.ProgressGauge.MinorTicks = [];
            app.ProgressGauge.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel_2);
            ylabel(app.UIAxes, 'Front')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.LineStyleOrder = {'- o'; '-'};
            app.UIAxes.LineStyleOrderIndex = 2;
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-1 -0.9 0 0.9 1];
            app.UIAxes.XTickLabel = {'90deg'; '90deg'; 'center'; '270deg'; '270deg'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-1 -0.9 0 0.9 1];
            app.UIAxes.YTickLabel = {'180deg'; '180deg'; ''; '0deg'; '0deg'};
            app.DegreeAdjustmentSliderLabel = uilabel(app.Panel_2);
            app.DegreeAdjustmentSliderLabel.Text = 'Degree Adjustment';
            app.DegreeAdjustmentSlider = uislider(app.Panel_2);
            app.DegreeAdjustmentSlider.Limits = [0 360];
            app.DegreeAdjustmentSlider.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.DegreeAdjustmentSlider.ValueChangedFcn = createCallbackFcn(app, @DegreeAdjustmentSliderValueChanged, true);
            app.DegreeAdjustmentSlider.ValueChangingFcn = createCallbackFcn(app, @DegreeAdjustmentSliderValueChanging, true);
            app.DegreeEditFieldLabel = uilabel(app.Panel_2);
            app.DegreeEditFieldLabel.Text = 'Degree';
            app.DegreeEditField = uieditfield(app.Panel_2, 'numeric');
            app.DegreeEditField.Limits = [0 360];
            app.DegreeEditField.Editable = 'off';
            app.PlaySoundButton = uibutton(app.Panel_2, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.ConfirmChoiceButton = uibutton(app.Panel_2, 'push');
            app.ConfirmChoiceButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmChoiceButtonPushed, true);
            app.ConfirmChoiceButton.Text = 'Confirm Choice';
            app.SubjectEditFieldLabel = uilabel(app.Panel_2);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.Panel_2, 'numeric');
            app.SubjectEditField.Limits = [1 Inf];
            app.SubjectEditField.Value = 1;
            app.FSHZEditFieldLabel = uilabel(app.Panel_2);
            app.FSHZEditFieldLabel.Enable = 'off';
            app.FSHZEditFieldLabel.Text = 'FS(HZ)';
            app.FSHZEditField = uieditfield(app.Panel_2, 'numeric');
            app.FSHZEditField.ValueDisplayFormat = '%.0f';
            app.FSHZEditField.Editable = 'off';
            app.FSHZEditField.Enable = 'off';
            app.FSHZEditField.Visible = 'off';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.PausingLabel = uilabel(app.UIFigure);
            app.PausingLabel.Text = 'Pausing...';
            app.TrainingButton = uibutton(app.UIFigure, 'state');
            app.TrainingButton.ValueChangedFcn = createCallbackFcn(app, @TrainingButtonValueChanged, true);
            app.TrainingButton.Text = 'Training';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.InstructionTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionTextAreaLabel.Text = 'Instruction:';
            app.InstructionTextArea = uitextarea(app.UIFigure);
            app.InstructionTextArea.Editable = 'off';
            app.InstructionTextArea.Value = {'1. Click Play Sound Button you will hear 3 repeated sound in your headphone.'; ''; '2, Think about where the sound coming from horizontally, using the Slide to pick the angle.'; ''; '3. Click Confirm Choice Button to confirm your choice. Then you can hear next sound by Click Play Sound Button.'; ''; '4. If you need to Pause the test, click Pause Button upper right of the Interface. '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = horizontal_App_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end